classdef two_tank_sysid_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DoubleTankProcess            matlab.ui.Figure
        SystemSettingPanel           matlab.ui.container.Panel
        StartStopSwitch              matlab.ui.control.Switch
        SimRealSwitch                matlab.ui.control.Switch
        SimulatorspeedupLabel        matlab.ui.control.Label
        SimulatorSpeedUpDropDown     matlab.ui.control.DropDown
        TapSwitch                    matlab.ui.control.Switch
        PlotSettingPanel             matlab.ui.container.Panel
        TspanEditFieldLabel          matlab.ui.control.Label
        TspanEditField               matlab.ui.control.NumericEditField
        YminEditFieldLabel           matlab.ui.control.Label
        YminEditField                matlab.ui.control.NumericEditField
        YmaxEditFieldLabel           matlab.ui.control.Label
        YmaxEditField                matlab.ui.control.NumericEditField
        AxesResetButton              matlab.ui.control.Button
        SignalsPanel                 matlab.ui.container.Panel
        ReferenceCheckBox            matlab.ui.control.CheckBox
        tank1CheckBox                matlab.ui.control.CheckBox
        tank2CheckBox                matlab.ui.control.CheckBox
        ControlSignalCheckBox        matlab.ui.control.CheckBox
        PpartCheckBox                matlab.ui.control.CheckBox
        IpartCheckBox                matlab.ui.control.CheckBox
        DpartCheckBox                matlab.ui.control.CheckBox
        CapturePanel                 matlab.ui.container.Panel
        CaptureButton                matlab.ui.control.Button
        SecondsToCaptureEditField    matlab.ui.control.NumericEditField
        SecondUnit                   matlab.ui.control.Label
        ControllerParameterPanel     matlab.ui.container.Panel
        InputSlider                  matlab.ui.control.Slider
        InputEditField_2Label        matlab.ui.control.Label
        InputEditField               matlab.ui.control.NumericEditField
        ManualRECSwitch              matlab.ui.control.Switch
        TankSwitch                   matlab.ui.control.Switch
        SignalfileEditFieldLabel     matlab.ui.control.Label
        SignalfileEditField          matlab.ui.control.EditField
        OpenSignalButton             matlab.ui.control.Button
        SamplingtimesEditFieldLabel  matlab.ui.control.Label
        SamplingtimeEditField        matlab.ui.control.NumericEditField
        RunButton                    matlab.ui.control.Button
        DataAquisitionPanel          matlab.ui.container.Panel
        DataAquisition               matlab.ui.control.UIAxes
        Tank1GaugeLabel              matlab.ui.control.Label
        tank1Gauge                   matlab.ui.control.LinearGauge
        Tank2GaugeLabel              matlab.ui.control.Label
        tank2Gauge                   matlab.ui.control.LinearGauge
        tank1Overflow                matlab.ui.control.Lamp
        tank2Overflow                matlab.ui.control.Lamp
    end


    properties (Access = public)
        % --- COMPONENTS: main --- %
        HW
        SW
        system
        REC
        Manual
        controller
        
        % --- COMPONENTS: timer --- %
        Timer
        AppIsRunning
        
        % --- COMPONENTS: plotting --- %
        figureOption
        ref_plot       % handle: reference data
        tank1_plot     % handle: tank1 plot
        tank2_plot     % handle: tank2 plot
        controlSignal_plot
        Ppart_plot
        Ipart_plot
        Dpart_plot
        
        % --- COMPONENTS: tuner --- %
        ControllerTuner
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('tankClass')
            addpath('tankTuner')
            tankFcn.ProcessCheck(app);
            tankFcn.SysidProcessPrepare(app);
        end

        % Callback function
        function AntiwindupSwitchValueChanged(app, event)
            switch event.Value
                case 'On'
                    app.PID.AntiWindUp_flag = true;
                case 'Off'
                    app.PID.AntiWindUp_flag = false;
            end
        end

        % Button pushed function: AxesResetButton
        function AxesResetButtonPushed(app, event)
            stop(app.Timer)
            app.TspanEditField.Value = 60;
            app.YminEditField.Value = 0;
            app.YmaxEditField.Value = 100;
            app.figureOption.axis_lim(1) = -60;
            app.figureOption.NumSampleToPlot = 60*20;
            app.figureOption.axis_lim(3) = 0;
            app.figureOption.axis_lim(4) = 100;
            tankFcn.PlotPrepare(app)
            start(app.Timer)
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            tankFcn.PlotCapture(app)
        end

        % Value changed function: ControlSignalCheckBox
        function ControlSignalCheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.controlSignal_plot,'YData',NaN(app.figureOption.NumSampleToPlot,1));
            end
        end

        % Close request function: DoubleTankProcess
        function DoubleTankProcessCloseRequest(app, event)
            tankFcn.ProcessStop(app);
            tankFcn.ProcessDelete(app);
            delete(app);
        end

        % Value changed function: DpartCheckBox
        function DpartCheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.Dpart_plot,'YData',NaN(app.figureOption.NumSampleToPlot,1));
            end
        end

        % Value changed function: InputEditField, InputSlider
        function InputValueChanged(app, event)
            app.InputEditField.Value = event.Value;
            app.InputSlider.Value = event.Value;
            app.Manual.controlSignal_before=event.Value;
        end

        % Value changed function: IpartCheckBox
        function IpartCheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.Ipart_plot,'YData',NaN(app.figureOption.NumSampleToPlot,1));
            end
        end

        % Callback function
        function IresetButtonPushed(app, event)
            app.PID.Ie = 0;
        end

        % Callback function
        function KValueChanged(app, event)
            app.PID.K = event.Value;
        end

        % Value changed function: ManualRECSwitch
        function ManualRECSwitchValueChanged(app, event)
            switch event.Value
                case 'Manual'
                    tankFcn.Manual_mode(app);
                case 'REC'
                    tankFcn.REC_mode(app, false);
            end
        end

        % Value changed function: PpartCheckBox
        function PpartCheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.Ppart_plot,'YData',NaN(app.figureOption.NumSampleToPlot,1));
            end
        end

        % Callback function
        function RefValueChanged(app, event)
            app.HW.ref = event.Value;
            app.SW.ref = event.Value;
            app.RefEditField.Value = event.Value;
            app.RefSlider.Value = event.Value;
        end

        % Value changed function: ReferenceCheckBox
        function ReferenceCheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.ref_plot,'YData',NaN(app.figureOption.NumSampleToPlot,1));
            end
        end

        % Callback function
        function SaveButtonPushed(app, event)
            % --- CONTROLLER PARAMETER SETTING --- %
            app.PID.K=app.ControllerTuner.Kp;
            app.PID.Ti=app.ControllerTuner.Ti;
            app.PID.Td=app.ControllerTuner.Td;
            % --- APP EditField SETTING --- %
            app.KEditField.Value=app.ControllerTuner.Kp;
            app.TiEditField.Value=app.ControllerTuner.Ti;
            app.TdEditField.Value=app.ControllerTuner.Td;
        end

        % Value changed function: SimRealSwitch
        function SimRealSwitchValueChanged(app, event)
            switch event.Value
                case 'Simulator'
                    tankFcn.SW_mode(app);
                case 'Real time'
                    tankFcn.HW_mode(app);
            end
        end

        % Value changed function: SimulatorSpeedUpDropDown
        function SimulatorSpeedUpDropDownValueChanged(app, event)
            switch event.Value
                case '1x'; scale = 5;
                case '2x'; scale = 4;
                case '3x'; scale = 3;
                case '4x'; scale = 2;
                case '5x'; scale = 1;
            end
            if app.AppIsRunning
                stop(app.Timer)
                app.Timer.Period = app.system.Ts*(scale/5);
                start(app.Timer)
            end
        end

        % Value changed function: StartStopSwitch
        function StartStopSwitchCallback(app, event)
            app.StartStopSwitch.Enable = 'Off';
            switch event.Value
                case 'Start'
                    tankFcn.ProcessStart(app);
                case 'Stop'
                    tankFcn.ProcessStop(app);
            end
            app.StartStopSwitch.Enable = 'On';
        end

        % Value changed function: TankSwitch
        function TankSwitchValueChanged(app, event)
            switch event.Value
                case 'Tank1'
                   app.HW.tankChoice=1;
                   app.SW.tankChoice=1;
                case 'Tank2'
                   app.HW.tankChoice=2;
                   app.SW.tankChoice=2;
            end
        end

        % Value changed function: TapSwitch
        function TapSwitchValueChanged(app, event)
            switch event.Value
                case 'Tap open'
                    app.SW.tap_flag = true;
                case 'Tap close'
                    app.SW.tap_flag = false;
            end
        end

        % Callback function
        function TdValueChanged(app, event)
            app.controller.Td = event.Value;
        end

        % Callback function
        function TiValueChanged(app, event)
            % --- TI UPDATE --- %
            if event.Value>99999
                app.TiEditField.Value = Inf;
                app.controller.Ti = Inf;
                app.controller.Ie=0;
            else
                app.controller.Ti = event.Value;
                app.controller.Ie = app.controller.Ie*(event.Value/app.controller.Ti);
            end
        end

        % Value changed function: TspanEditField
        function TspanEditFieldValueChanged(app, event)
            if app.AppIsRunning
                stop(app.Timer)
                tankFcn.TspanReset(app,event)
                start(app.Timer)
            else
                tankFcn.TspanReset(app,event)
            end
            
        end

        % Callback function
        function TunerButtonPushed(app, event)
            if isempty(app.ControllerTuner) || ~isvalid(app.ControllerTuner)
                app.ControllerTuner=TunerClass();
            end
        end

        % Value changed function: YmaxEditField
        function YmaxEditFieldValueChanged(app, event)
            app.figureOption.axis_lim(1,4)=event.Value;
            app.DataAquisition.YLim(2)=event.Value;
        end

        % Value changed function: YminEditField
        function YminEditFieldValueChanged(app, event)
            app.figureOption.axis_lim(1,3)=event.Value;
            app.DataAquisition.YLim(1)=event.Value;
        end

        % Value changed function: tank1CheckBox
        function tank1CheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.tank1_plot,'YData',NaN(app.figureOption.NumSampleToPlot,1));
            end
        end

        % Value changed function: tank2CheckBox
        function tank2CheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.tank2_plot,'YData',NaN(app.figureOption.NumSampleToPlot,1));
            end
        end

        % Button pushed function: OpenSignalButton
        function OpenSignalButtonPushed(app, event)
            [signal_file, file_path] = uigetfile('*.mat');
            if signal_file ~= 0
                full_path = strcat(file_path, signal_file);
                set(app.SignalfileEditField, 'Value', full_path)
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if ~app.REC.isRunning
                file_path = app.SignalfileEditField.Value(1:end-4);
                try
                    signal_data = load(file_path);
                catch ME
                    error_msg = strcat('File not found', file_path);
                    opts = struct('WindowStyle', 'modal');
                    errordlg(error_msg, 'File error', opts);
                    return
                end
                signal = signal_data.u;
                sampling_time = app.SamplingtimeEditField.Value;            
                if sampling_time > 0
                    sequence_set = app.REC.set_control_sequence(signal, sampling_time);
                else
                    sequence_set = false;
                end
                if sequence_set
                    app.REC.isRunning = true;
                    tankFcn.REC_mode(app, true);
                end
            else
                %fig = uifigure;
                msg = 'Do you really want to stop the current run?';
                title = 'Confirm Stop Run';
                switch uiconfirm(app.DoubleTankProcess, msg, title)
                    case 'OK'
                        app.REC.stop();
                        tankFcn.REC_mode(app, false);
                    case 'Cancel'
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DoubleTankProcess = uifigure;
            app.DoubleTankProcess.Name = 'Double tank process';
            app.DoubleTankProcess.CloseRequestFcn = createCallbackFcn(app, @DoubleTankProcessCloseRequest, true);
            app.SystemSettingPanel = uipanel(app.DoubleTankProcess);
            app.SystemSettingPanel.Title = 'System settings';
            app.StartStopSwitch = uiswitch(app.SystemSettingPanel, 'slider');
            app.StartStopSwitch.Items = {'Stop', 'Start'};
            app.StartStopSwitch.ValueChangedFcn = createCallbackFcn(app, @StartStopSwitchCallback, true);
            app.StartStopSwitch.Value = 'Stop';
            app.SimRealSwitch = uiswitch(app.SystemSettingPanel, 'slider');
            app.SimRealSwitch.Items = {'Simulator', 'Real time'};
            app.SimRealSwitch.ValueChangedFcn = createCallbackFcn(app, @SimRealSwitchValueChanged, true);
            app.SimRealSwitch.Value = 'Simulator';
            app.SimulatorspeedupLabel = uilabel(app.SystemSettingPanel);
            app.SimulatorspeedupLabel.Text = 'Simulator speed up';
            app.SimulatorSpeedUpDropDown = uidropdown(app.SystemSettingPanel);
            app.SimulatorSpeedUpDropDown.Items = {'1x', '2x', '3x', '4x', '5x'};
            app.SimulatorSpeedUpDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulatorSpeedUpDropDownValueChanged, true);
            app.SimulatorSpeedUpDropDown.Value = '1x';
            app.TapSwitch = uiswitch(app.SystemSettingPanel, 'slider');
            app.TapSwitch.Items = {'Tap close', 'Tap open'};
            app.TapSwitch.ValueChangedFcn = createCallbackFcn(app, @TapSwitchValueChanged, true);
            app.TapSwitch.Value = 'Tap close';
            app.PlotSettingPanel = uipanel(app.DoubleTankProcess);
            app.PlotSettingPanel.Title = 'Plot settings';
            app.TspanEditFieldLabel = uilabel(app.PlotSettingPanel);
            app.TspanEditFieldLabel.Text = 'T span';
            app.TspanEditField = uieditfield(app.PlotSettingPanel, 'numeric');
            app.TspanEditField.Limits = [10 600];
            app.TspanEditField.ValueDisplayFormat = '%11.3g';
            app.TspanEditField.ValueChangedFcn = createCallbackFcn(app, @TspanEditFieldValueChanged, true);
            app.TspanEditField.Value = 60;
            app.YminEditFieldLabel = uilabel(app.PlotSettingPanel);
            app.YminEditFieldLabel.Text = 'Y min';
            app.YminEditField = uieditfield(app.PlotSettingPanel, 'numeric');
            app.YminEditField.Limits = [-50 45];
            app.YminEditField.ValueDisplayFormat = '%11.3g';
            app.YminEditField.ValueChangedFcn = createCallbackFcn(app, @YminEditFieldValueChanged, true);
            app.YmaxEditFieldLabel = uilabel(app.PlotSettingPanel);
            app.YmaxEditFieldLabel.Text = 'Y max';
            app.YmaxEditField = uieditfield(app.PlotSettingPanel, 'numeric');
            app.YmaxEditField.Limits = [50 150];
            app.YmaxEditField.ValueDisplayFormat = '%11.3g';
            app.YmaxEditField.ValueChangedFcn = createCallbackFcn(app, @YmaxEditFieldValueChanged, true);
            app.YmaxEditField.Value = 100;
            app.AxesResetButton = uibutton(app.PlotSettingPanel, 'push');
            app.AxesResetButton.ButtonPushedFcn = createCallbackFcn(app, @AxesResetButtonPushed, true);
            app.AxesResetButton.Text = 'Axes reset';
            app.SignalsPanel = uipanel(app.PlotSettingPanel);
            app.SignalsPanel.Title = 'Signals';
            app.ReferenceCheckBox = uicheckbox(app.SignalsPanel);
            app.ReferenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReferenceCheckBoxValueChanged, true);
            app.ReferenceCheckBox.Text = 'Reference';
            app.tank1CheckBox = uicheckbox(app.SignalsPanel);
            app.tank1CheckBox.ValueChangedFcn = createCallbackFcn(app, @tank1CheckBoxValueChanged, true);
            app.tank1CheckBox.Text = 'Tank1';
            app.tank1CheckBox.Value = true;
            app.tank2CheckBox = uicheckbox(app.SignalsPanel);
            app.tank2CheckBox.ValueChangedFcn = createCallbackFcn(app, @tank2CheckBoxValueChanged, true);
            app.tank2CheckBox.Text = 'Tank2';
            app.tank2CheckBox.Value = true;
            app.ControlSignalCheckBox = uicheckbox(app.SignalsPanel);
            app.ControlSignalCheckBox.ValueChangedFcn = createCallbackFcn(app, @ControlSignalCheckBoxValueChanged, true);
            app.ControlSignalCheckBox.Text = 'Input';
            app.ControlSignalCheckBox.Value = true;
            app.PpartCheckBox = uicheckbox(app.SignalsPanel);
            app.PpartCheckBox.ValueChangedFcn = createCallbackFcn(app, @PpartCheckBoxValueChanged, true);
            app.PpartCheckBox.Text = 'P-part';
            app.IpartCheckBox = uicheckbox(app.SignalsPanel);
            app.IpartCheckBox.ValueChangedFcn = createCallbackFcn(app, @IpartCheckBoxValueChanged, true);
            app.IpartCheckBox.Text = 'I-part';
            app.DpartCheckBox = uicheckbox(app.SignalsPanel);
            app.DpartCheckBox.ValueChangedFcn = createCallbackFcn(app, @DpartCheckBoxValueChanged, true);
            app.DpartCheckBox.Text = 'D-part';
            app.CapturePanel = uipanel(app.PlotSettingPanel);
            app.CapturePanel.Title = 'Seconds to capture';
            app.CaptureButton = uibutton(app.CapturePanel, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.SecondsToCaptureEditField = uieditfield(app.CapturePanel, 'numeric');
            app.SecondsToCaptureEditField.Limits = [10 600];
            app.SecondsToCaptureEditField.Value = 60;
            app.SecondUnit = uilabel(app.CapturePanel);
            app.SecondUnit.Text = 's';
            app.ControllerParameterPanel = uipanel(app.DoubleTankProcess);
            app.ControllerParameterPanel.Title = 'Controller settings';
            app.InputSlider = uislider(app.ControllerParameterPanel);
            app.InputSlider.MajorTicks = [];
            app.InputSlider.MajorTickLabels = {};
            app.InputSlider.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputSlider.MinorTicks = [];
            app.InputEditField_2Label = uilabel(app.ControllerParameterPanel);
            app.InputEditField_2Label.Text = 'Input';
            app.InputEditField = uieditfield(app.ControllerParameterPanel, 'numeric');
            app.InputEditField.Limits = [0 100];
            app.InputEditField.ValueDisplayFormat = '%11.3g';
            app.InputEditField.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.ManualRECSwitch = uiswitch(app.ControllerParameterPanel, 'slider');
            app.ManualRECSwitch.Items = {'Manual', 'REC'};
            app.ManualRECSwitch.ValueChangedFcn = createCallbackFcn(app, @ManualRECSwitchValueChanged, true);
            app.ManualRECSwitch.Value = 'Manual';
            app.TankSwitch = uiswitch(app.ControllerParameterPanel, 'slider');
            app.TankSwitch.Items = {'Tank1', 'Tank2'};
            app.TankSwitch.ValueChangedFcn = createCallbackFcn(app, @TankSwitchValueChanged, true);
            app.TankSwitch.Value = 'Tank1';
            app.SignalfileEditFieldLabel = uilabel(app.ControllerParameterPanel);
            app.SignalfileEditFieldLabel.Text = 'Signal file';
            app.SignalfileEditField = uieditfield(app.ControllerParameterPanel, 'text');
            app.SignalfileEditField.Editable = 'off';
            app.OpenSignalButton = uibutton(app.ControllerParameterPanel, 'push');
            app.OpenSignalButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSignalButtonPushed, true);
            app.OpenSignalButton.Text = 'Open';
            app.SamplingtimesEditFieldLabel = uilabel(app.ControllerParameterPanel);
            app.SamplingtimesEditFieldLabel.Text = 'Sampling time (s)';
            app.SamplingtimeEditField = uieditfield(app.ControllerParameterPanel, 'numeric');
            app.SamplingtimeEditField.Value = 4;
            app.RunButton = uibutton(app.ControllerParameterPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.DataAquisitionPanel = uipanel(app.DoubleTankProcess);
            app.DataAquisitionPanel.Title = 'Data aquisition';
            app.DataAquisition = uiaxes(app.DataAquisitionPanel);
            xlabel(app.DataAquisition, 'Time [s]')
            ylabel(app.DataAquisition, 'Y [%]')
            app.Tank1GaugeLabel = uilabel(app.DataAquisitionPanel);
            app.Tank1GaugeLabel.Text = 'Tank1';
            app.tank1Gauge = uigauge(app.DataAquisitionPanel, 'linear');
            app.Tank2GaugeLabel = uilabel(app.DataAquisitionPanel);
            app.Tank2GaugeLabel.Text = 'Tank2';
            app.tank2Gauge = uigauge(app.DataAquisitionPanel, 'linear');
            app.tank1Overflow = uilamp(app.DataAquisitionPanel);
            app.tank2Overflow = uilamp(app.DataAquisitionPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = two_tank_sysid_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DoubleTankProcess)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DoubleTankProcess)
        end
    end
end